classdef brainlab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainLabBrainImageAnalysisToolUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RegistrationTab                 matlab.ui.container.Tab
        PreregistrationAlignmentPanel   matlab.ui.container.Panel
        DropDown_selectAlignMethod      matlab.ui.control.DropDown
        BrainExtractionDeskullingPanel  matlab.ui.container.Panel
        DeskullFactorEditField          matlab.ui.control.EditField
        DeskullFactorEditFieldLabel     matlab.ui.control.Label
        ButtonGroup_ct_or_mri           matlab.ui.container.ButtonGroup
        mri                             matlab.ui.control.RadioButton
        ct                              matlab.ui.control.RadioButton
        ExecuteButton                   matlab.ui.control.Button
        LoadDataPanel                   matlab.ui.container.Panel
        EditField_lesion_file           matlab.ui.control.EditField
        Transform8bitButton             matlab.ui.control.Button
        ConvertMEDxFilesButton          matlab.ui.control.Button
        Button_lesion_file              matlab.ui.control.Button
        LesionimageLabel                matlab.ui.control.Label
        EditField_brain_file            matlab.ui.control.EditField
        Button_brain_file               matlab.ui.control.Button
        BrainImageLabel                 matlab.ui.control.Label
        EditField_working_dir           matlab.ui.control.EditField
        Button_working_dir              matlab.ui.control.Button
        WorkingDirectoryLabel           matlab.ui.control.Label
        RegistrationPanel               matlab.ui.container.Panel
        Panel_atlas                     matlab.ui.container.Panel
        ReloadButton                    matlab.ui.control.Button
        ListBox_atlas                   matlab.ui.control.ListBox
        AtlasListBoxLabel               matlab.ui.control.Label
        Panel_template                  matlab.ui.container.Panel
        ListBox_template                matlab.ui.control.ListBox
        TemplateListBoxLabel            matlab.ui.control.Label
        Panel_heal                      matlab.ui.container.Panel
        RegistrationLabel               matlab.ui.control.Label
        DropDown_selectRegAlg           matlab.ui.control.DropDown
        EditField_smooth_factor         matlab.ui.control.NumericEditField
        SmoothFactorLabel               matlab.ui.control.Label
        CheckBox_heal                   matlab.ui.control.CheckBox
        VLSMTab                         matlab.ui.container.Tab
        ExecuteVLSMButton               matlab.ui.control.Button
        OutputPanel                     matlab.ui.container.Panel
        OutputFolderLabel               matlab.ui.control.Label
        EditField_output_folder         matlab.ui.control.EditField
        Button_output_folder            matlab.ui.control.Button
        ParametersPanel                 matlab.ui.container.Panel
        MultipleBehaviorsDropDown       matlab.ui.control.DropDown
        MultipleBehaviorsDropDownLabel  matlab.ui.control.Label
        ApplyLesionvolumecorrectionCheckBox  matlab.ui.control.CheckBox
        UseDropDown                     matlab.ui.control.DropDown
        UseDropDownLabel                matlab.ui.control.Label
        PeakvoxelvEditField             matlab.ui.control.NumericEditField
        PeakvoxelvEditFieldLabel        matlab.ui.control.Label
        patientsLabel                   matlab.ui.control.Label
        NoofPermutationsEditField       matlab.ui.control.NumericEditField
        NoofPermutationsEditFieldLabel  matlab.ui.control.Label
        MinOverlapEditField             matlab.ui.control.NumericEditField
        MinOverlapEditFieldLabel        matlab.ui.control.Label
        BehavioralScoreDropDown         matlab.ui.control.DropDown
        SignificanceLevelEditField      matlab.ui.control.NumericEditField
        SignificanceLevelEditFieldLabel  matlab.ui.control.Label
        CorrectionforMultipleComparisonsDropDown  matlab.ui.control.DropDown
        CorrectionforMultipleComparisonsDropDownLabel  matlab.ui.control.Label
        StatisticalTestDropDown         matlab.ui.control.DropDown
        StatisticalTestDropDownLabel    matlab.ui.control.Label
        BehaviorScoreTypeDropDown       matlab.ui.control.DropDown
        BehaviorScoreTypeDropDownLabel  matlab.ui.control.Label
        LoadDataPanel_2                 matlab.ui.container.Panel
        EditField_behavior_file         matlab.ui.control.EditField
        Button_behavior_file            matlab.ui.control.Button
        BehaviorFileLabel               matlab.ui.control.Label
        EditField_lesions_folder        matlab.ui.control.EditField
        Button_lesions_folder           matlab.ui.control.Button
        NormalizedLesionsFolderLabel    matlab.ui.control.Label
        RLSMTab                         matlab.ui.container.Tab
        MLSMTab                         matlab.ui.container.Tab
        Panel_status                    matlab.ui.container.Panel
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        BrainLabBrainImageAnalysisToolLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DefaultWorkingDir = '.\data' % Working Directory
        DefaultBrainFilename = '*.nii.gz'                
        DefaultLesionFilename = '*.nii.gz'
        DefaultBatchTableFilename = '*.csv'
        DefaultBehaviorFilename = '*.csv'
        SupportedFileFormats = {'*.nii.gz','Compressed NIfTI files (*.nii.gz)';...
            '*.nii','NIfTI files (*.nii)';...
            '*.hdr','Analyze Files (MEDx) (*.hdr)'}; %Supported file formats that will be displayed in the dialog box        
        SupportedBatchTableFormats = '*.csv';
        MsgBox = NaN;
        
        
        %Default abbrevions. The first cell displayed in the GUI; the
        %second cell is the parameter for LESYMAP, the third is the a
        %parameter for NiiStat (if relevant)


        vlsm_continous = 1; % VLSM contnious (1) or binary (0) data


    end
    
    properties (Access = public)
        
        %FWER control options:
        %1st cell: GUI display
        %2nd cell: LESYMAP parameter, '' if not supported
        %3rd cell: NiiStat parameter, NaN if not supported
        %4th cell: supported statistical tests for LESYMAP
        %5th cell: supported statistical test for NiiStat
        DefaultNPerm = 1000;
        fwer = struct('bonf',{'Bonferroni','bonferroni','0'}, ...
                      'fdr',{'FDR','fdr','-1'}, ...
                      'perm',{'Permutations','FWERperm','1000'}, ...
                      'cperm',{'Cluster perm.','clusterPerm',''})
        perm_and_cluster = {'Permutations', 'FDR', 'Bonferroni', 'Cluster perm.'};
        perm_no_cluster = {'Permutations', 'FDR', 'Bonferroni'};
        no_perm = {'FDR', 'Bonferroni'};


        %Statistical test
        %1st cell: GUI display
        %2nd cell: LESYMAP parameter, '' if not supported
        %3rd cell: NiiStat parameter, NaN if not supported
        %4th cell: supported statistical tests for LESYMAP
        %5th cell: supported statistical test for NiiStat       
        stest = struct('t',{'t-test / linear reg.','regresfast','1'},...
                       'BM',{'Brunner-Munzel','BMfast','NaN'},...
                       'chisq',{'Chi-squared','chisq','NaN'},...
                       'Lieb',{'Liebermeister','','1'});
                   
        DefaultContinousTests = {'t-test / linear reg.','Brunner-Munzel'};
        DefaultBinaryTests = {'Chi-squared','Liebermeister'};
      
    end
    
    methods (Access = private)
        
        function new_fn = check_n_convert_hdr(app,fn)
            
            pos = findstr(fn,'.nii.gz');
            if isempty(pos)
                pos = findstr(fn,'.nii');
                if isempty(pos)
                    pos = findstr(fn,'.hdr');
                    if isempty(pos)
                        ext = '';
                    else
                        ext = '.hdr';
                    end
                else
                    ext = '.nii';
                end
            else
                ext = '.nii.gz';
            end
            
            
            if strcmpi(ext,'.hdr')
                full_fn = [app.EditField_working_dir.Value,'\',fn];
                set_status (app,1,sprintf('Converting HDR file "%s" to NIfTI...',fn))
                convert_analyze2nifti (full_fn)
                set_status(app,0)
                pos = findstr(fn,'.hdr');              
                new_fn = [fn(1:pos-1), '.nii.gz'];
            else
                new_fn = fn;
            end
                
        end
        
        function  set_status(app, status, varargin)
            if nargin > 2
                text = varargin{1};
            else
                text = 'Ready';
            end
            app.StatusLabel.Text = text;
            if status
                app.StatusLamp.Color = 'r';                
            else
                app.StatusLamp.Color = 'g';                
            end
            drawnow;
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set_status(app,1,'Initializing...')
            
            %invoke brainlab docker-container:
            invoke_container;
            
            %Load the list of atlases and templates:
            ReloadButtonPushed(app);
            BatchCheckBoxValueChanged(app);
            
            %VLSM init:
            BehaviorScoreTypeDropDownValueChanged(app)
            StatisticalTestDropDownValueChanged(app)
            set_status(app,0)
            
        end

        % Button pushed function: Button_working_dir
        function Button_working_dirPushed(app, event)
            dirname = uigetdir(app.EditField_working_dir.Value,"Select working directory");
            if (dirname ~= 0)
                app.EditField_working_dir.Value = dirname;  
            end
        end

        % Button pushed function: Button_brain_file
        function Button_brain_filePushed(app, event)
            [fn,~,indx] = uigetfile(app.SupportedFileFormats,'Select brain image file',app.EditField_working_dir.Value');

            if (fn ~= 0 )
                app.EditField_brain_file.Value = fn;        
            end
        end

        % Button pushed function: Button_lesion_file
        function Button_lesion_filePushed(app, event)
            fn = uigetfile(app.SupportedFileFormats,'Select lesion image file',app.EditField_working_dir.Value');
            if (fn ~= 0)
                app.EditField_lesion_file.Value= fn;        
            end
        end

        % Value changed function: EditField_working_dir
        function EditField_working_dirValueChanged(app, event)
            value = app.EditField_working_dir.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_working_dir.Value = app.DefaultWorkingDir;
            end
        end

        % Value changed function: EditField_brain_file
        function EditField_brain_fileValueChanged(app, event)
            value = app.EditField_brain_file.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_brain_file.Value = app.DefaultBrainFilename;
            end
        end

        % Value changed function: EditField_lesion_file
        function EditField_lesion_fileValueChanged(app, event)
            value = app.EditField_lesion_file.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_lesion_file.Value = app.DefaultLesionFilename;
            end
        end

        % Value changed function: CheckBox_heal
        function CheckBox_healValueChanged(app, event)
            value = app.CheckBox_heal.Value;
            if value
                app.EditField_smooth_factor.Enable = true;
            else
                app.EditField_smooth_factor.Enable = false;
            end
            
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
           %Retrieve data from GUI (batch-mode insensitive):
           heal_op = app.CheckBox_heal.Value;
           smooth_op = app.EditField_smooth_factor.Value;
           atlas_name = app.ListBox_atlas.Value;
           switch lower(app.ListBox_template.Value)
                case 'scct (ct only!)'
                    temp_op = 'scct';
                case 'atlas-specific for mri and scct for ct'
                    temp_op = 'atlas';
                otherwise
                    temp_op = 'atlas';
           end
            
            
            batch_mode = app.BatchCheckBox.Value;
            
            if ~batch_mode
                %Check if both brain and lesion images are valid
                wd = app.EditField_working_dir.Value;
                x = [wd,'\',app.EditField_brain_file.Value];
                les = [wd,'\',app.EditField_lesion_file.Value];
                if (~isfile(x) || ~(isfile(les)))
                    if (~isfile(x))
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                    else
                        msg = errordlg(sprintf('Lesion image "%s" was not found',les));                    
                    end
               else
                                    
                    %If either brain or lesion are not in nii/nii.gz file format
                    %(one which oro.nifti::readNIftI() can read...) - convert
                    fn = app.EditField_brain_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_brain_file.Value = new_fn;
                    end


                    fn = app.EditField_lesion_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_lesion_file.Value = new_fn;
                    end
                    
                    %Retrieve data from GUI:
                    p_folder = app.EditField_working_dir.Value;
                    x_fname = app.EditField_brain_file.Value;
                    lesion_fname = app.EditField_lesion_file.Value;
                    is_ct = app.ct.Value;
                    fraq = str2double(app.DeskullFactorEditField.Value);
                    if ((fraq < 0) || (fraq > 1))
                        fraq = 0.5;
                    end
 
                    
                    fileformat = 'nii';            
                    
                    %Execute WSL-R registration engine:
                    %function normalize_brain (patient_folder, x_fname, lesion_fname, file_format, atlas_name, is_ct, temp_op, heal_op, smooth_op)
                    set_status(app,1,'Normalizing brain & lesion...') 
                    normalize_brain(p_folder,x_fname,lesion_fname,fileformat,atlas_name,is_ct,temp_op,heal_op,smooth_op,fraq);
                    set_status(app,0)
                end %
            else %batch_mode
                working_dir = app.EditField_working_dir.Value;
                %Check if batch file exists
                batch_fn = [working_dir,'\',app.EditField_batchtable.Value];
                if ~isfile(batch_fn)
                    errordlg(sprintf('batch file "%s" was not found.',batch_fn))
                else
                   
                   batch_normalize_brains([working_dir,'\'], batch_fn, atlas_name, temp_op, heal_op, smooth_op)
                end
            end
        end

        % Close request function: BrainLabBrainImageAnalysisToolUIFigure
        function BrainLabBrainImageAnalysisToolUIFigureCloseRequest(app, event)
            close_container;
            delete(app)
            %diary off
        end

        % Button pushed function: ConvertMEDxFilesButton
        function ConvertMEDxFilesButtonPushed(app, event)
            batch_mode = app.BatchCheckBox.Value;           
            wd = app.EditField_working_dir.Value;      
            if ~batch_mode
                %Check if both brain and lesion images are valid
  
                x = [wd,'\',app.EditField_brain_file.Value];
                if isfile(x)
                    fn = app.EditField_brain_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_brain_file.Value = new_fn;
                    end 
                end
                              
                les = [wd,'\',app.EditField_lesion_file.Value];
                if isfile(les)
                   fn = app.EditField_lesion_file.Value;
                    new_fn = check_n_convert_hdr(app,fn);
                    if ~strcmpi(fn,new_fn)
                        app.EditField_lesion_file.Value = new_fn;
                    end                  
                end
                
                if (~isfile(x)) && ~(isfile(les))
                    if (~isfile(x))
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                    else
                        msg = errordlg(sprintf('Lesion image "%s" was not found',les));                    
                    end
                end
 
            
            else
                
               batch_fn = app.EditField_batchtable.Value;
               batch_full_fn = [wd,'\',batch_fn];
               if ~isfile(batch_full_fn)
                   errordlg (sprintf('Batch file "%s" was not found',batch_full_fn))
               else
                   batch_convert_analyze2nifti(wd,batch_full_fn)
               end
            end %batch_mode
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            work_dir = app.EditField_working_dir.Value;
            t = readtable([work_dir, '\Templates_Atlases\atlas_list.csv'],'Delimiter',',','PreserveVariableNames',true);
            app.ListBox_atlas.Items = t.("atlas.name");
            if isempty(app.ListBox_atlas.Value)
                app.ListBox_atlas.Value = app.ListBox_atlas.Items{1};
            end
        end

        % Callback function: not associated with a component
        function EditField_batchtableValueChanged(app, event)
            value = app.EditField_batchtable.Value;
            if ~isfile(value) %checks if file name is valid
                app.EditField_brain_file.Value = app.DefaultBatchTableFilename;
            end           
        end

        % Callback function: not associated with a component
        function Button_batchtablePushed(app, event)
            
            [fn,~,indx] = uigetfile(app.SupportedBatchTableFormats,'Select batch table file',app.EditField_working_dir.Value);

            if (fn ~= 0)
                app.EditField_batchtable.Value = fn;        
            end
        end

        % Callback function: not associated with a component
        function BatchCheckBoxValueChanged(app, event)
            value = app.BatchCheckBox.Value;
            if value == 1
                %batch mode
                %Disable Brain Image and Lesion Image selection, as all
                %selection input is drawn from table file
                app.EditField_brain_file.Enable = 'off';
                app.EditField_behavior_file.Editable = 'off';
                app.Button_behavior_file.Enable = 'off';
 
                app.EditField_lesion_file.Enable = 'off';
                app.EditField_lesion_file.Editable = 'off';
                app.Button_lesion_file.Enable = 'off';
                
                %Disable CT/MRI radio button, from the same reason
                app.ct.Enable = 'off';
                app.mri.Enable = 'off';
                
                %Enable Batch table file selection
                app.EditField_batchtable.Enable = 'on';
                app.EditField_batchtable.Editable = 'on';
                app.Button_batchtable.Enable = 'on';
                
            else
                %single mode
                %batch mode
                %Enable Brain Image and Lesion Image selection
                app.EditField_brain_file.Enable = 'on';
                app.EditField_behavior_file.Editable = 'on';
                app.Button_behavior_file.Enable = 'on';
 
                app.EditField_lesion_file.Enable = 'on';
                app.EditField_lesion_file.Editable = 'on';
                app.Button_lesion_file.Enable = 'on';
                
                %Enable CT/MRI radio button
                app.ct.Enable = 'on';
                app.mri.Enable = 'on';
                
                %Disable Batch table file selection
                app.EditField_batchtable.Enable = 'off';
                app.EditField_batchtable.Editable = 'off';
                app.Button_batchtable.Enable = 'off';
                
            end             
        end

        % Value changed function: EditField_lesions_folder
        function EditField_lesions_folderValueChanged(app, event)
          
            value = app.EditField_lesions_folder.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_lesions_folder.Value = app.DefaultWorkingDir;
            end
        end

        % Button pushed function: Button_lesions_folder
        function Button_lesions_folderPushed(app, event)
                dirname = uigetdir(app.EditField_lesions_folder.Value,"Select lesions directory");
            if (dirname ~= 0)
                app.EditField_lesions_folder.Value = dirname;  
            end            
        end

        % Value changed function: EditField_behavior_file
        function EditField_behavior_fileValueChanged(app, event)
           
            
        value = app.EditField_behavior_file.Value;
 
            if ~isfile(value) %checks if file name is valid
                app.EditField_behavior_file.Value = app.DefaultBehaviorFilename;
            end
        end

        % Button pushed function: Button_behavior_file
        function Button_behavior_filePushed(app, event)
             
            [fn,~,indx] = uigetfile(app.SupportedBatchTableFormats,'Select batch table file',app.EditField_lesions_folder.Value);

            if (fn ~= 0)
                app.EditField_behavior_file.Value = fn;        
            end           
        end

        % Value changed function: BehaviorScoreTypeDropDown
        function BehaviorScoreTypeDropDownValueChanged(app, event)
            value = app.BehaviorScoreTypeDropDown.Value;
            if strcmpi(value,'continous')
                app.vlsm_continous = 1;
            else
                app.vlsm_continous = 0;
            end
            
            if app.vlsm_continous
                app.StatisticalTestDropDown.Items = app.DefaultContinousTests;
            else
               app.StatisticalTestDropDown.Items = app.DefaultBinaryTests; 
            end
            app.StatisticalTestDropDown.Value = app.StatisticalTestDropDown.Items{1};
            app.StatisticalTestDropDownValueChanged(app)
        end

        % Value changed function: StatisticalTestDropDown
        function StatisticalTestDropDownValueChanged(app, event)
            value = app.StatisticalTestDropDown.Value;
                     
            %Change Multiple Comparisons and Software Use dropbox accordingly
  
            switch lower(value)
                case lower(app.stest(1).t)
                    app.CorrectionforMultipleComparisonsDropDown.Items = app.perm_and_cluster;
                    app.UseDropDown.Items = {'LESYMAP','NiiStat'};
                case lower(app.stest(1).BM)
                    app.CorrectionforMultipleComparisonsDropDown.Items = app.perm_no_cluster;
                    app.UseDropDown.Items = {'LESYMAP'};
                case lower(app.stest(1).chisq)
                    app.CorrectionforMultipleComparisonsDropDown.Items = app.no_perm;
                    app.UseDropDown.Items = {'LESYMAP'};                   
                case lower(app.stest(1).Lieb)
                    app.CorrectionforMultipleComparisonsDropDown.Items = app.perm_no_cluster;
                    app.UseDropDown.Items = {'NiiStat'};
                    
            end
            app.CorrectionforMultipleComparisonsDropDown.Value = app.CorrectionforMultipleComparisonsDropDown.Items{1};
            app.UseDropDown.Value = app.UseDropDown.Items{1};
            
        end

        % Value changed function: CorrectionforMultipleComparisonsDropDown
        function CorrectionforMultipleComparisonsDropDownValueChanged(app, event)
            value = app.CorrectionforMultipleComparisonsDropDown.Value;
            if strcmpi(value,app.fwer(1).cperm)
                app.UseDropDown.Items = {'LESYMAP'};
                app.UseDropDown.Value = 'LESYMAP';
            else
                value2 = app.StatisticalTestDropDown.Value;
                use_list = {'LESYMAP','NiiStat'};
                switch value2
                    case app.stest(1).t, use_list = {'LESYMAP','NiiStat'};
                    case app.stest(1).BM, use_list = {'LESYMAP'};
                    case app.stest(1).chisq, use_list = {'LESYMAP'};
                    case app.stest(1).Lieb, use_list = {'NiiStat'};
                          
                end
                app.UseDropDown.Items = use_list;
                app.UseDropDown.Value = use_list{1};
            end
        end

        % Button pushed function: ExecuteVLSMButton
        function ExecuteVLSMButtonPushed(app, event)
            %Retrieve information from GUI
         
            img_path = app.EditField_lesions_folder.Value;
            analysis_tab_file = [img_path ,'\',app.EditField_behavior_file.Value];
            out_dir = app.EditField_output_folder.Value;
            
            software = app.UseDropDown.Value;
            if strcmpi(software,'lesymap')
                indx = 2;
            else
                indx = 3;
            end
            
            gui_stest = app.StatisticalTestDropDown.Value;
            meth = '';
            switch gui_stest
                case app.stest(1).t, meth = app.stest(indx).t;
                case app.stest(1).BM, meth = app.stest(indx).BM;
                case app.stest(1).chisq, meth = app.stest(indx).chisq;
                case app.stest(1).Lieb, meth = app.stest(indx).Lieb;
            end


            correct_lesion_size = app.ApplyLesionvolumecorrectionCheckBox.Value;
            
            fwer_gui = app.CorrectionforMultipleComparisonsDropDown.Value;
            fwer = '';
            switch fwer_gui
                case app.fwer(1).bonf, fwer = app.fwer(indx).bonf;
                case app.fwer(1).fdr, fwer = app.fwer(indx).fdr;
                case app.fwer(1).perm, fwer = app.fwer(indx).perm;                         
                case app.fwer(1).cperm, fwer = app.fwer(indx).cperm;
            end
            
            if indx == 3
               if strcmp(fwer_gui,app.fwer(1).perm)
                  fwer_niistat = app.NoofPermutationsEditField.Value;
               else
                  fwer_niistat = str2double(fwer); 
               end
            end
         
            
            nperm = app.NoofPermutationsEditField.Value;
            alpha = app.SignificanceLevelEditField.Value;
            min_overlap = app.MinOverlapEditField.Value;
            peak_voxel = app.PeakvoxelvEditField.Value;
            
            behave_dir = app.BehavioralScoreDropDown.Value;
            
            if indx == 2
                %LESYMAP
                if correct_lesion_size, LesionSize = 'behavior'; else, LesionSize = 'none'; end
                if strcmpi(behave_dir,'performance'), flip_sign = 0; else, flip_sign = 1; end
                set_status(app,1,'Running Voxel-based Lesion Symptom mapping using LESYMAP package...') 
                vlsm_lesymap(img_path, analysis_tab_file, out_dir, meth, LesionSize, fwer, alpha, flip_sign, min_overlap, nperm, peak_voxel)
                set_status(app,0)
            else %NiiStat
                
                vlsm_niistat(img_path, analysis_tab_file, out_dir, fwer_niistat, alpha, min_overlap, correct_lesion_size);
            end
            
           
        end

        % Button pushed function: Button_output_folder
        function Button_output_folderPushed(app, event)
                dirname = uigetdir(app.EditField_output_folder.Value,"Select output folder");
            if (dirname ~= 0)
                app.EditField_output_folder.Value = dirname;  
            end                       
        end

        % Callback function
        function EditField_output_folderValueChanged(app, event)
           value = app.EditField_output_folder.Value;
            if ~isfolder(value) %checks if folder name is valid
                app.EditField_output_folder.Value = app.DefaultWorkingDir;
            end           
        end

        % Button pushed function: Transform8bitButton
        function Transform8bitButtonPushed(app, event)
              wd = app.EditField_working_dir.Value;
              fn = app.EditField_brain_file.Value;
              x = [wd ,'\', fn];
                if (~isfile(x))
                        msg = errordlg(sprintf('Brain image "%s" was not found',x));                    
                else    
                        set_status(app,1,'Transforming8-bit brain image...')
                        new_fn = transform_8bit_files(wd,fn);
                        if ~isempty(new_fn)
                            app.EditField_brain_file.Value = new_fn;
                        end
                        set_status(app,0)
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BrainLabBrainImageAnalysisToolUIFigure = uifigure('Visible', 'off');
            app.BrainLabBrainImageAnalysisToolUIFigure.Name = 'BrainLab: Brain Image Analysis Tool';
            app.BrainLabBrainImageAnalysisToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @BrainLabBrainImageAnalysisToolUIFigureCloseRequest, true);
            app.BrainLabBrainImageAnalysisToolLabel = uilabel(app.BrainLabBrainImageAnalysisToolUIFigure);
            app.BrainLabBrainImageAnalysisToolLabel.Text = 'BrainLab: Brain Image Analysis Tool';
            app.Panel_status = uipanel(app.BrainLabBrainImageAnalysisToolUIFigure);
            app.StatusLabel = uilabel(app.Panel_status);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Panel_status);
            app.TabGroup = uitabgroup(app.BrainLabBrainImageAnalysisToolUIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.Panel_heal = uipanel(app.RegistrationPanel);
            app.CheckBox_heal = uicheckbox(app.Panel_heal);
            app.CheckBox_heal.ValueChangedFcn = createCallbackFcn(app, @CheckBox_healValueChanged, true);
            app.CheckBox_heal.Text = 'Enantiomorphic';
            app.CheckBox_heal.Value = true;
            app.SmoothFactorLabel = uilabel(app.Panel_heal);
            app.SmoothFactorLabel.Text = 'Smooth Factor:';
            app.EditField_smooth_factor = uieditfield(app.Panel_heal, 'numeric');
            app.EditField_smooth_factor.Limits = [0 6];
            app.EditField_smooth_factor.Value = 1.5;
            app.DropDown_selectRegAlg = uidropdown(app.Panel_heal);
            app.DropDown_selectRegAlg.Items = {'Rigid', 'Affine', 'SyN (recommended)'};
            app.DropDown_selectRegAlg.Value = 'SyN (recommended)';
            app.RegistrationLabel = uilabel(app.Panel_heal);
            app.RegistrationLabel.Text = 'Registration:';
            app.Panel_template = uipanel(app.RegistrationPanel);
            app.TemplateListBoxLabel = uilabel(app.Panel_template);
            app.TemplateListBoxLabel.Text = 'Template:';
            app.ListBox_template = uilistbox(app.Panel_template);
            app.ListBox_template.Items = {'Atlas-Specific', 'Atlas-specific for MRI and scct for CT', ''};
            app.ListBox_template.Value = 'Atlas-Specific';
            app.Panel_atlas = uipanel(app.RegistrationPanel);
            app.AtlasListBoxLabel = uilabel(app.Panel_atlas);
            app.AtlasListBoxLabel.Text = 'Atlas:';
            app.ListBox_atlas = uilistbox(app.Panel_atlas);
            app.ListBox_atlas.Items = {};
            app.ListBox_atlas.Value = {};
            app.ReloadButton = uibutton(app.Panel_atlas, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.LoadDataPanel = uipanel(app.RegistrationTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.WorkingDirectoryLabel = uilabel(app.LoadDataPanel);
            app.WorkingDirectoryLabel.Text = {'Working Directory:'; ''};
            app.Button_working_dir = uibutton(app.LoadDataPanel, 'push');
            app.Button_working_dir.ButtonPushedFcn = createCallbackFcn(app, @Button_working_dirPushed, true);
            app.Button_working_dir.Text = 'Browse...';
            app.EditField_working_dir = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_working_dir.ValueChangedFcn = createCallbackFcn(app, @EditField_working_dirValueChanged, true);
            app.EditField_working_dir.Value = '.\data';
            app.BrainImageLabel = uilabel(app.LoadDataPanel);
            app.BrainImageLabel.Text = {'Brain Image:'; ''};
            app.Button_brain_file = uibutton(app.LoadDataPanel, 'push');
            app.Button_brain_file.ButtonPushedFcn = createCallbackFcn(app, @Button_brain_filePushed, true);
            app.Button_brain_file.Text = 'Browse...';
            app.EditField_brain_file = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_brain_file.ValueChangedFcn = createCallbackFcn(app, @EditField_brain_fileValueChanged, true);
            app.EditField_brain_file.Value = '*.nii.gz';
            app.LesionimageLabel = uilabel(app.LoadDataPanel);
            app.LesionimageLabel.Text = 'Lesion image:';
            app.Button_lesion_file = uibutton(app.LoadDataPanel, 'push');
            app.Button_lesion_file.ButtonPushedFcn = createCallbackFcn(app, @Button_lesion_filePushed, true);
            app.Button_lesion_file.Text = 'Browse...';
            app.ConvertMEDxFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.ConvertMEDxFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertMEDxFilesButtonPushed, true);
            app.ConvertMEDxFilesButton.Text = 'Convert MEDx Files';
            app.Transform8bitButton = uibutton(app.LoadDataPanel, 'push');
            app.Transform8bitButton.ButtonPushedFcn = createCallbackFcn(app, @Transform8bitButtonPushed, true);
            app.Transform8bitButton.Text = 'Transform 8bit';
            app.EditField_lesion_file = uieditfield(app.LoadDataPanel, 'text');
            app.EditField_lesion_file.ValueChangedFcn = createCallbackFcn(app, @EditField_lesion_fileValueChanged, true);
            app.EditField_lesion_file.Value = '*.nii.gz';
            app.ExecuteButton = uibutton(app.RegistrationTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.BrainExtractionDeskullingPanel = uipanel(app.RegistrationTab);
            app.BrainExtractionDeskullingPanel.Title = 'Brain Extraction (Deskulling)';
            app.ButtonGroup_ct_or_mri = uibuttongroup(app.BrainExtractionDeskullingPanel);
            app.ct = uiradiobutton(app.ButtonGroup_ct_or_mri);
            app.ct.Text = 'CT';
            app.ct.Value = true;
            app.mri = uiradiobutton(app.ButtonGroup_ct_or_mri);
            app.mri.Text = 'MRI';
            app.DeskullFactorEditFieldLabel = uilabel(app.BrainExtractionDeskullingPanel);
            app.DeskullFactorEditFieldLabel.Text = 'Deskull Factor';
            app.DeskullFactorEditField = uieditfield(app.BrainExtractionDeskullingPanel, 'text');
            app.DeskullFactorEditField.Value = '0.4';
            app.PreregistrationAlignmentPanel = uipanel(app.RegistrationTab);
            app.PreregistrationAlignmentPanel.Title = 'Pre-registration Alignment';
            app.DropDown_selectAlignMethod = uidropdown(app.PreregistrationAlignmentPanel);
            app.DropDown_selectAlignMethod.Items = {'Rigid (recommended)', 'Affine', 'None (not recommended)'};
            app.DropDown_selectAlignMethod.Value = 'Rigid (recommended)';
            app.VLSMTab = uitab(app.TabGroup);
            app.VLSMTab.Title = 'VLSM';
            app.LoadDataPanel_2 = uipanel(app.VLSMTab);
            app.LoadDataPanel_2.Title = 'Load Data';
            app.NormalizedLesionsFolderLabel = uilabel(app.LoadDataPanel_2);
            app.NormalizedLesionsFolderLabel.Text = 'Normalized Lesions Folder';
            app.Button_lesions_folder = uibutton(app.LoadDataPanel_2, 'push');
            app.Button_lesions_folder.ButtonPushedFcn = createCallbackFcn(app, @Button_lesions_folderPushed, true);
            app.Button_lesions_folder.Text = 'Browse...';
            app.EditField_lesions_folder = uieditfield(app.LoadDataPanel_2, 'text');
            app.EditField_lesions_folder.ValueChangedFcn = createCallbackFcn(app, @EditField_lesions_folderValueChanged, true);
            app.EditField_lesions_folder.Value = 'C:\Scripts\Docker\Shared';
            app.BehaviorFileLabel = uilabel(app.LoadDataPanel_2);
            app.BehaviorFileLabel.Text = 'Behavior File:';
            app.Button_behavior_file = uibutton(app.LoadDataPanel_2, 'push');
            app.Button_behavior_file.ButtonPushedFcn = createCallbackFcn(app, @Button_behavior_filePushed, true);
            app.Button_behavior_file.Text = 'Browse...';
            app.EditField_behavior_file = uieditfield(app.LoadDataPanel_2, 'text');
            app.EditField_behavior_file.ValueChangedFcn = createCallbackFcn(app, @EditField_behavior_fileValueChanged, true);
            app.EditField_behavior_file.Value = '*.csv';
            app.ParametersPanel = uipanel(app.VLSMTab);
            app.ParametersPanel.Title = 'Parameters';
            app.BehaviorScoreTypeDropDownLabel = uilabel(app.ParametersPanel);
            app.BehaviorScoreTypeDropDownLabel.Text = 'Behavior Score Type';
            app.BehaviorScoreTypeDropDown = uidropdown(app.ParametersPanel);
            app.BehaviorScoreTypeDropDown.Items = {'Continous', 'Binary'};
            app.BehaviorScoreTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BehaviorScoreTypeDropDownValueChanged, true);
            app.BehaviorScoreTypeDropDown.Value = 'Continous';
            app.StatisticalTestDropDownLabel = uilabel(app.ParametersPanel);
            app.StatisticalTestDropDownLabel.Text = 'Statistical Test';
            app.StatisticalTestDropDown = uidropdown(app.ParametersPanel);
            app.StatisticalTestDropDown.Items = {'t-test', 'Brunner-Munzel', 'chi-square', 'Liebermeister'};
            app.StatisticalTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticalTestDropDownValueChanged, true);
            app.StatisticalTestDropDown.Value = 't-test';
            app.CorrectionforMultipleComparisonsDropDownLabel = uilabel(app.ParametersPanel);
            app.CorrectionforMultipleComparisonsDropDownLabel.Text = {'Correction for '; 'Multiple Comparisons'};
            app.CorrectionforMultipleComparisonsDropDown = uidropdown(app.ParametersPanel);
            app.CorrectionforMultipleComparisonsDropDown.Items = {'Permutations', 'FDR', 'None', 'Cluster-based Perm', 'Bonferroni'};
            app.CorrectionforMultipleComparisonsDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionforMultipleComparisonsDropDownValueChanged, true);
            app.CorrectionforMultipleComparisonsDropDown.Value = 'Permutations';
            app.SignificanceLevelEditFieldLabel = uilabel(app.ParametersPanel);
            app.SignificanceLevelEditFieldLabel.Text = 'Significance Level';
            app.SignificanceLevelEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SignificanceLevelEditField.Limits = [0 1];
            app.SignificanceLevelEditField.Value = 0.05;
            app.BehavioralScoreDropDown = uidropdown(app.ParametersPanel);
            app.BehavioralScoreDropDown.Items = {'Performance', 'Impairment'};
            app.BehavioralScoreDropDown.Value = 'Performance';
            app.MinOverlapEditFieldLabel = uilabel(app.ParametersPanel);
            app.MinOverlapEditFieldLabel.Text = 'Min. Overlap';
            app.MinOverlapEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.MinOverlapEditField.Limits = [1 Inf];
            app.MinOverlapEditField.RoundFractionalValues = 'on';
            app.MinOverlapEditField.ValueDisplayFormat = '%.0f';
            app.MinOverlapEditField.Value = 1;
            app.NoofPermutationsEditFieldLabel = uilabel(app.ParametersPanel);
            app.NoofPermutationsEditFieldLabel.Text = 'No. of Permutations';
            app.NoofPermutationsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NoofPermutationsEditField.Limits = [0 Inf];
            app.NoofPermutationsEditField.RoundFractionalValues = 'on';
            app.NoofPermutationsEditField.ValueDisplayFormat = '%.0f';
            app.patientsLabel = uilabel(app.ParametersPanel);
            app.patientsLabel.Text = 'patients';
            app.PeakvoxelvEditFieldLabel = uilabel(app.ParametersPanel);
            app.PeakvoxelvEditFieldLabel.Text = 'Peak voxel (v) =';
            app.PeakvoxelvEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PeakvoxelvEditField.Limits = [1 100];
            app.PeakvoxelvEditField.RoundFractionalValues = 'on';
            app.PeakvoxelvEditField.ValueDisplayFormat = '%.0f';
            app.PeakvoxelvEditField.Value = 1;
            app.UseDropDownLabel = uilabel(app.ParametersPanel);
            app.UseDropDownLabel.Text = 'Use:';
            app.UseDropDown = uidropdown(app.ParametersPanel);
            app.UseDropDown.Items = {'Auto', 'LESYMAP', 'NiiStat'};
            app.UseDropDown.Value = 'LESYMAP';
            app.ApplyLesionvolumecorrectionCheckBox = uicheckbox(app.ParametersPanel);
            app.ApplyLesionvolumecorrectionCheckBox.Text = 'Apply Lesion volume correction';
            app.MultipleBehaviorsDropDownLabel = uilabel(app.ParametersPanel);
            app.MultipleBehaviorsDropDownLabel.Text = 'Multiple Behaviors:';
            app.MultipleBehaviorsDropDown = uidropdown(app.ParametersPanel);
            app.MultipleBehaviorsDropDown.Items = {'Independent', 'Conjunction', 'GLM'};
            app.MultipleBehaviorsDropDown.Value = 'Independent';
            app.OutputPanel = uipanel(app.VLSMTab);
            app.OutputPanel.Title = 'Output';
            app.Button_output_folder = uibutton(app.OutputPanel, 'push');
            app.Button_output_folder.ButtonPushedFcn = createCallbackFcn(app, @Button_output_folderPushed, true);
            app.Button_output_folder.Text = 'Browse...';
            app.EditField_output_folder = uieditfield(app.OutputPanel, 'text');
            app.EditField_output_folder.Value = 'C:\Scripts\Docker\Shared';
            app.OutputFolderLabel = uilabel(app.OutputPanel);
            app.OutputFolderLabel.Text = 'Output Folder:';
            app.ExecuteVLSMButton = uibutton(app.VLSMTab, 'push');
            app.ExecuteVLSMButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteVLSMButtonPushed, true);
            app.ExecuteVLSMButton.Text = 'Execute';
            app.RLSMTab = uitab(app.TabGroup);
            app.RLSMTab.Title = 'RLSM';
            app.MLSMTab = uitab(app.TabGroup);
            app.MLSMTab.Title = 'MLSM';
            % Show the figure after all components are created
            app.BrainLabBrainImageAnalysisToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = brainlab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainLabBrainImageAnalysisToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainLabBrainImageAnalysisToolUIFigure)
        end
    end
end